classdef tiemnghiem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        NghimTab                      matlab.ui.container.Tab
        gx_2                          matlab.ui.control.Label
        fab                           matlab.ui.control.Label
        SolveButton                   matlab.ui.control.Button
        lap                           matlab.ui.control.NumericEditField
        NumberofiterationsLabel       matlab.ui.control.Label
        kq                            matlab.ui.control.NumericEditField
        ResultLabel                   matlab.ui.control.Label
        ChoosesolutionmethodDropDown  matlab.ui.control.DropDown
        ChoosesolutionmethodDropDownLabel  matlab.ui.control.Label
        ss                            matlab.ui.control.NumericEditField
        b                             matlab.ui.control.NumericEditField
        a                             matlab.ui.control.NumericEditField
        EntertheallowederrorLabel     matlab.ui.control.Label
        pt                            matlab.ui.control.EditField
        NhpphngtrnhLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChoosesolutionmethodDropDown
        function ChoosesolutionmethodDropDownValueChanged(app, event)
            switch app.ChoosesolutionmethodDropDown.Value
                case 'Chia đôi'
                    app.b.Visible = 'on';
                    app.fab.Text = 'Enter [a b]: ';
                case 'Newton'
                    app.b.Visible = 'on';
                    app.fab.Text = 'Enter [a b]: ';
                case 'Lặp'
                    app.b.Visible = 'on';
                    app.fab.Text = 'Enter [a b]: ';
            end
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
% Lấy giá trị đầu vào
method = app.ChoosesolutionmethodDropDown.Value;
fx = str2func(['@(x)' app.pt.Value]);
saiso = app.ss.Value;
a = app.a.Value;
b = app.b.Value;

% Khởi tạo kết quả
result = 0;
n = 0;
switch method
    case 'Chia đôi'
        % Gọi hàm chia đôi
        [result, n] = chia_doi(fx, a, b, saiso);
    case 'Newton'
    % Tính Newton với đạo hàm
    daoham = @(x) (fx(x + 1e-6) - fx(x)) / 1e-6; % Sử dụng đạo hàm gần đúng
    x0 = (a + b) / 2; % Khởi tạo giá trị ban đầu là trung điểm [a, b]
    [result, n] = newton(fx, daoham, x0, saiso);
    case 'Lặp'
        % Phương pháp lặp
        g = @(x) (a + b) / 2 - fx((a + b) / 2); % Sử dụng khoảng để tính g(x)
        x0 = (a + b) / 2;
        [result, n] = lap(fx, g, x0, saiso);
end

% Hiển thị kết quả
app.kq.Value = result;
app.lap.Value = n;

% Vẽ đồ thị
fplot(app.UIAxes, fx, [a, b], 'DisplayName', 'f(x)');
hold(app.UIAxes, 'on');
plot(app.UIAxes, result, fx(result), 'ro', 'DisplayName', 'Nghiệm gần đúng');
xlabel(app.UIAxes, 'x');
ylabel(app.UIAxes, 'f(x)');
title(app.UIAxes, 'Đồ thị của phương trình f(x)');
legend(app.UIAxes, 'show');
grid(app.UIAxes, 'on');
hold(app.UIAxes, 'off');

% Hàm chia đôi
function [result, n] = chia_doi(fx, a, b, saiso)
    n = 0;
    while (b - a) / 2 > saiso
        c = (a + b) / 2;
        if fx(c) == 0
            break;
        elseif fx(a) * fx(c) < 0
            b = c;
        else
            a = c;
        end
        n = n + 1;
    end
    result = (a + b) / 2;
end

% Hàm Newton
function [result, n] = newton(fx, dfx, x0, saiso)
    n = 0;
    while true
        x1 = x0 - fx(x0) / dfx(x0);
        n = n + 1;
        if abs(x1 - x0) < saiso
            break;
        end
        x0 = x1;
    end
    result = x1;
end
% Hàm lặp
function [result, n] = lap(fx, g, x0, saiso)
    n = 0;
    while true
        x1 = g(x0);
        n = n + 1;
        if abs(x1 - x0) < saiso
            break;
        end
        x0 = x1;
    end
    result = x1;
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghimTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpphngtrnhLabel = uilabel(app.NghimTab);
            app.NhpphngtrnhLabel.Text = 'Enter f(x): ';
            app.pt = uieditfield(app.NghimTab, 'text');
            app.EntertheallowederrorLabel = uilabel(app.NghimTab);
            app.EntertheallowederrorLabel.Text = 'Enter the allowed error:';
            app.a = uieditfield(app.NghimTab, 'numeric');
            app.b = uieditfield(app.NghimTab, 'numeric');
            app.ss = uieditfield(app.NghimTab, 'numeric');
            app.ChoosesolutionmethodDropDownLabel = uilabel(app.NghimTab);
            app.ChoosesolutionmethodDropDownLabel.Text = 'Choose solution method';
            app.ChoosesolutionmethodDropDown = uidropdown(app.NghimTab);
            app.ChoosesolutionmethodDropDown.Items = {'...', 'Chia đôi', 'Lặp', 'Newton'};
            app.ChoosesolutionmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosesolutionmethodDropDownValueChanged, true);
            app.ChoosesolutionmethodDropDown.Value = 'Chia đôi';
            app.ResultLabel = uilabel(app.NghimTab);
            app.ResultLabel.Text = 'Result';
            app.kq = uieditfield(app.NghimTab, 'numeric');
            app.NumberofiterationsLabel = uilabel(app.NghimTab);
            app.NumberofiterationsLabel.Text = 'Number of iterations';
            app.lap = uieditfield(app.NghimTab, 'numeric');
            app.SolveButton = uibutton(app.NghimTab, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.fab = uilabel(app.NghimTab);
            app.fab.Text = 'Enter [a b]: ';
            app.gx_2 = uilabel(app.NghimTab);
            app.gx_2.Text = 'Solve the equation to find the solution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tiemnghiem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end